classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        k2imaginerEditField_2          matlab.ui.control.EditField
        k2imaginerEditField_2Label     matlab.ui.control.Label
        k1imaginerEditField_2          matlab.ui.control.EditField
        k1imaginerEditField_2Label     matlab.ui.control.Label
        k2imaginerEditField            matlab.ui.control.EditField
        k2imaginerEditFieldLabel       matlab.ui.control.Label
        k1imaginerEditField            matlab.ui.control.EditField
        k1imaginerEditFieldLabel       matlab.ui.control.Label
        FundementalFrequencyEditField  matlab.ui.control.EditField
        FundementalFrequencyEditFieldLabel  matlab.ui.control.Label
        k1EditField                    matlab.ui.control.EditField
        k1EditFieldLabel               matlab.ui.control.Label
        k0EditField                    matlab.ui.control.EditField
        k0EditFieldLabel               matlab.ui.control.Label
        k2EditField_2                  matlab.ui.control.EditField
        k2EditField_2Label             matlab.ui.control.Label
        k1EditField_2                  matlab.ui.control.EditField
        k1EditField_2Label             matlab.ui.control.Label
        k2EditField                    matlab.ui.control.EditField
        k2EditFieldLabel               matlab.ui.control.Label
        GoButton                       matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)

            a=app.k0EditField.Value;
            b=app.k1EditField.Value;
            c=app.k2EditField.Value;
            d=app.k1EditField_2.Value;
            e=app.k2EditField_2.Value;
            a=str2num(a);
            b=str2num(b);
            c=str2num(c);
            d=str2num(d);
            e=str2num(e);
            b1=app.k1imaginerEditField.Value;
            c1=app.k2imaginerEditField.Value;
            d1=app.k1imaginerEditField_2.Value;
            e1=app.k2imaginerEditField_2.Value;
            b1=str2num(b1);
            c1=str2num(c1);
            d1=str2num(d1);
            e1=str2num(e1);
            fs=100;
            t=0:1/fs:5;
            temel_frekans=app.FundementalFrequencyEditField.Value;
            temel_frekans=str2num(temel_frekans);
            b3=b;
            c3=c;
            d3=d;
            e3=e;
            b4=exp(1j*temel_frekans*pi*t*2)*(b3+b1*1j);
            c4=exp(1j*temel_frekans*pi*t*2*2)*(c3+c1*1j);
            d4=exp(1j*temel_frekans*pi*t*2*(-1))*(d3+d1*1j);
            e4=exp(1j*temel_frekans*pi*t*2*(-2))*(e3+e1*1j);
            f=b4+c4+d4+e4+a*1j;
            for i=1:length(t)
                   b=exp(1j*temel_frekans*pi*t(i)*2)*(b3+b1*1j);
                   c=exp(1j*temel_frekans*pi*t(i)*2*2)*(c3+c1*1j);
                   d=exp(1j*temel_frekans*pi*t(i)*2*(-1))*(d3+d1*1j);
                 e=exp(1j*temel_frekans*pi*t(i)*2*(-2))*(e3+e1*1j);
                 k=exp(1j*2*pi*200*t(i));
                 set(gca, 'XAxisLocation', 'origin', 'YAxisLocation', 'origin');
                 creal= real(b)+real(c);
                 cimag= imag(b)+imag(c);
                 dreal= creal+real(d);
                 dimag= cimag+imag(d);
                 ereal= dreal+real(e);
                 eimag= dimag+imag(e);
                 plot(app.UIAxes,[0 real(b)],[0 imag(b)],[real(b) creal],[imag(b) cimag],[creal dreal],[cimag dimag],[dreal ereal],[dimag eimag]);
                 plot(app.UIAxes2,(1:i)/fs,imag(f(1:i)));
                 xlim(app.UIAxes,[-10, 10]);
                 ylim(app.UIAxes,[-10, 10]);
                 xlim(app.UIAxes2,[0, 32]);
                 ylim(app.UIAxes2,[-16, 16]);
                 drawnow;
     
                 
            end
                

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {545, 545};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {257, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GoButton = uibutton(app.LeftPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Tag = 'go';
            app.GoButton.Text = 'Go';
            app.k2EditFieldLabel = uilabel(app.LeftPanel);
            app.k2EditFieldLabel.Text = 'k=2';
            app.k2EditField = uieditfield(app.LeftPanel, 'text');
            app.k2EditField.Tag = 'k2';
            app.k2EditField.Value = '3';
            app.k1EditField_2Label = uilabel(app.LeftPanel);
            app.k1EditField_2Label.Text = 'k=-1';
            app.k1EditField_2 = uieditfield(app.LeftPanel, 'text');
            app.k1EditField_2.Tag = 'k3';
            app.k1EditField_2.Value = '3/2';
            app.k2EditField_2Label = uilabel(app.LeftPanel);
            app.k2EditField_2Label.Text = 'k=-2';
            app.k2EditField_2 = uieditfield(app.LeftPanel, 'text');
            app.k2EditField_2.Tag = 'k4';
            app.k2EditField_2.Value = '-3';
            app.k0EditFieldLabel = uilabel(app.LeftPanel);
            app.k0EditFieldLabel.Text = 'k=0';
            app.k0EditField = uieditfield(app.LeftPanel, 'text');
            app.k0EditField.Tag = 'k0';
            app.k0EditField.Value = '0';
            app.k1EditFieldLabel = uilabel(app.LeftPanel);
            app.k1EditFieldLabel.Text = 'k=1';
            app.k1EditField = uieditfield(app.LeftPanel, 'text');
            app.k1EditField.Tag = 'k1';
            app.k1EditField.Value = '-3/2';
            app.FundementalFrequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.FundementalFrequencyEditFieldLabel.Text = 'Fundemental Frequency';
            app.FundementalFrequencyEditField = uieditfield(app.LeftPanel, 'text');
            app.FundementalFrequencyEditField.Value = '3/2';
            app.k1imaginerEditFieldLabel = uilabel(app.LeftPanel);
            app.k1imaginerEditFieldLabel.Text = 'k=1(imaginer)';
            app.k1imaginerEditField = uieditfield(app.LeftPanel, 'text');
            app.k1imaginerEditField.Value = '7/8';
            app.k2imaginerEditFieldLabel = uilabel(app.LeftPanel);
            app.k2imaginerEditFieldLabel.Text = 'k=2(imaginer)';
            app.k2imaginerEditField = uieditfield(app.LeftPanel, 'text');
            app.k2imaginerEditField.Value = '8/7';
            app.k1imaginerEditField_2Label = uilabel(app.LeftPanel);
            app.k1imaginerEditField_2Label.Text = 'k=-1(imaginer)';
            app.k1imaginerEditField_2 = uieditfield(app.LeftPanel, 'text');
            app.k1imaginerEditField_2.Value = '-7/8';
            app.k2imaginerEditField_2Label = uilabel(app.LeftPanel);
            app.k2imaginerEditField_2Label.Text = 'k=-2(imaginer)';
            app.k2imaginerEditField_2 = uieditfield(app.LeftPanel, 'text');
            app.k2imaginerEditField_2.Value = '-8/7';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Discrete')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Sum')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end